classdef edu_course_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MATLABUIFigure  matlab.ui.Figure
        Install         matlab.ui.control.Button
        NotFound        matlab.ui.control.Label
        Start           matlab.ui.control.Button
        Store           matlab.ui.control.Button
        VerLabel        matlab.ui.control.Label
        UpdateLabel     matlab.ui.control.Label
        Update          matlab.ui.control.Button
        Help            matlab.ui.control.Button
    end

    
    properties (Access = private)
        dirname = "EduCourseFiles" % course files directory
        installed % files directory exists
        T % timer
        updater % Toolbox Updater
    end
    
    methods (Access = private)
        
        function wb = waitbar(app, title)
            wb = uiprogressdlg(app.MATLABUIFigure, 'Title', title, 'Indeterminate', 'on');
        end
        
        function error(app, msg)
            uialert(app.MATLABUIFigure, msg, 'Ошибка');
        end
        
        function done(app, msg)
            uialert(app.MATLABUIFigure, msg, 'Готово', 'Icon', 'success');
        end
        
        function installed = check_installed(app)
            installed = isfolder(app.dirname);
            app.installed = installed;
            app.NotFound.Visible = ~installed;
            app.Start.Enable = installed;
            app.Store.Enable = installed;
        end
        
        function check_update(app)
            try
                if app.updater.isonline() && app.updater.isupdate()
                    app.UpdateLabel.Visible = 'on';
                    app.Update.Visible = 'on';
                end
            catch e
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.T = timer('ExecutionMode', 'fixedRate', 'Period', 1, 'TimerFcn', @(~, ~)app.check_installed);
            start(app.T);
            app.updater = MATLABCourseforEducatorsUpdater;
            app.VerLabel.Text = [app.VerLabel.Text app.updater.TE.vc];
            app.check_update();
        end

        % Button pushed function: Start
        function StartButtonPushed(app, event)
            addEduCourseToPath;
            open('edu_course_content.mlx');
            app.MATLABUIFigureCloseRequest();
        end

        % Button pushed function: Install
        function InstallButtonPushed(app, event)
            install = true;
            if app.installed
                sel = uiconfirm(app.MATLABUIFigure, 'Текущий прогресс будет потерян. Вы уверены?', 'Подтвердите',...
                    'Options',{'Да','Отмена'}, 'DefaultOption',2,'CancelOption',2,'Icon','warning');
                if sel == "Да"
                    rmdir(app.dirname, 's');
                else
                    install = false;
                end
            end
            if install
                wb = app.waitbar('Копирование файлов...');
                dirpath = fullfile(fileparts(mfilename('fullpath')), app.dirname);
                copyfile(dirpath, fullfile(pwd, app.dirname));
                close(wb);
                app.StartButtonPushed();
            end
        end

        % Button pushed function: Store
        function StoreButtonPushed(app, event)
            wb = app.waitbar('Архивация файлов...');
            fs = dir(app.dirname);
            fs = fs(3:end);
            fs = {fs.name};
            zip(app.dirname, fs, app.dirname);
            close(wb);
            app.done("Файлы занятий помещены в архив " + app.dirname + ".zip");
        end

        % Close request function: MATLABUIFigure
        function MATLABUIFigureCloseRequest(app, event)
            stop(app.T);
            delete(app.T);
            delete(app);
        end

        % Button pushed function: Help
        function HelpButtonPushed(app, event)
            app.updater.TE.doc();
        end

        % Button pushed function: Update
        function UpdateButtonPushed(app, event)
            msg = sprintf('Вы хотите обновиться до версии v%s?', app.updater.vr);
            sel = uiconfirm(app.MATLABUIFigure, msg, 'Подтвердите',...
                'Options',{'Да','Отмена'}, 'DefaultOption',2,'CancelOption',2);
            if sel == "Да"
                pr = app.waitbar('Идёт обновление...');
                app.updater.update();
                close(pr);
                msg = 'Обновление завершено, программа будет перезапущена. Не забудьте установить новые файлы занятий';
                sel = uiconfirm(app.MATLABUIFigure, msg, 'Готово',...
                    'Options',{'ОК'}, 'DefaultOption', 1, 'Icon', 'success');
                app.done('Обновление завершено, программа будет перезапущена. Не забудьте установить новые рабочие файлы');
                app.MATLABUIFigureCloseRequest();
                edu_course_app;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MATLABUIFigure = uifigure;
            app.MATLABUIFigure.Name = 'Курс "MATLAB для преподавателей"';
            app.MATLABUIFigure.CloseRequestFcn = createCallbackFcn(app, @MATLABUIFigureCloseRequest, true);
            app.Install = uibutton(app.MATLABUIFigure, 'push');
            app.Install.ButtonPushedFcn = createCallbackFcn(app, @InstallButtonPushed, true);
            app.Install.Icon = 'icon-install.png';
            app.Install.Text = 'Установить курс в текущую папку';
            app.NotFound = uilabel(app.MATLABUIFigure);
            app.NotFound.Visible = 'off';
            app.NotFound.Text = 'Файлы занятий не найдены';
            app.Start = uibutton(app.MATLABUIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Start.Icon = 'icon-start.png';
            app.Start.Enable = 'off';
            app.Start.Text = 'Приступить';
            app.Store = uibutton(app.MATLABUIFigure, 'push');
            app.Store.ButtonPushedFcn = createCallbackFcn(app, @StoreButtonPushed, true);
            app.Store.Icon = 'icon-store.png';
            app.Store.Enable = 'off';
            app.Store.Text = 'Заархивировать файлы';
            app.VerLabel = uilabel(app.MATLABUIFigure);
            app.VerLabel.Text = 'Версия ';
            app.UpdateLabel = uilabel(app.MATLABUIFigure);
            app.UpdateLabel.Visible = 'off';
            app.UpdateLabel.Text = 'Доступно обновление';
            app.Update = uibutton(app.MATLABUIFigure, 'push');
            app.Update.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.Update.Icon = 'icon-install.png';
            app.Update.Visible = 'off';
            app.Update.Text = '';
            app.Help = uibutton(app.MATLABUIFigure, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.Help.Icon = 'icon-help.png';
            app.Help.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = edu_course_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MATLABUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MATLABUIFigure)
        end
    end
end